classdef BRM_Microscope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        AcquisitionTab                 matlab.ui.container.Tab
        AcquisitionStatusPanel         matlab.ui.container.Panel
        SaveDirectoryLampLabel         matlab.ui.control.Label
        SaveDirectoryLamp              matlab.ui.control.Lamp
        StitchingAcquisitionLampLabel  matlab.ui.control.Label
        StitchingAcquisitionLamp       matlab.ui.control.Lamp
        ZFocusLampLabel                matlab.ui.control.Label
        ZFocusLamp                     matlab.ui.control.Lamp
        GridStatusLampLabel            matlab.ui.control.Label
        GridStatusLamp                 matlab.ui.control.Lamp
        FileLocationLabel              matlab.ui.control.Label
        FileNameEditFieldLabel         matlab.ui.control.Label
        SystemStatusPanel              matlab.ui.container.Panel
        CameraStatusMicromanagerLabel  matlab.ui.control.Label
        FileName                       matlab.ui.control.EditField
        AcquireImagesButton            matlab.ui.control.Button
        Defaulttodate_time_Mag_acqtype_Label  matlab.ui.control.Label
        MagnificationLabel_2           matlab.ui.control.Label
        MicroscopyMethodLabel          matlab.ui.control.Label
        GridSizeLabel_2                matlab.ui.control.Label
        Magnification_status           matlab.ui.control.Label
        Microscopy_status              matlab.ui.control.Label
        Grid_size_status               matlab.ui.control.Label
        XYstageControlPanel            matlab.ui.container.Panel
        x_coord_entry                  matlab.ui.control.EditField
        y_coord_entry                  matlab.ui.control.EditField
        text54                         matlab.ui.control.Label
        text54_2                       matlab.ui.control.Label
        MoveXYmotorButton              matlab.ui.control.Button
        text54_3                       matlab.ui.control.Label
        text54_4                       matlab.ui.control.Label
        x_coord_text                   matlab.ui.control.Label
        SnapShotPreviewButton          matlab.ui.control.Button
        ImagePreviewPanel              matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        y_coord_text                   matlab.ui.control.Label
        SettingsTab                    matlab.ui.container.Tab
        ImageStitchingParametersPanel  matlab.ui.container.Panel
        GridSizeLabel                  matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        StartPositionLabel             matlab.ui.control.Label
        XEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        YEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        mmLabel                        matlab.ui.control.Label
        mmLabel_2                      matlab.ui.control.Label
        AutoFocusCheckBox              matlab.ui.control.CheckBox
        StitchingTypeDropDownLabel     matlab.ui.control.Label
        StitchingTypeDropDown          matlab.ui.control.DropDown
        ImageSettingsPanel             matlab.ui.container.Panel
        MagnificationLabel             matlab.ui.control.Label
        MagnificationDropDown          matlab.ui.control.DropDown
        ExposureTime1EditFieldLabel    matlab.ui.control.Label
        ExposureTime1EditField         matlab.ui.control.NumericEditField
        ExposureTime2EditFieldLabel    matlab.ui.control.Label
        ExposureTime2EditField         matlab.ui.control.NumericEditField
        msLabel_2                      matlab.ui.control.Label
        msLabel                        matlab.ui.control.Label
        AcquisitionPanel               matlab.ui.container.Panel
        MicroscopeSetupLabel           matlab.ui.control.Label
        MicroscopeSetupDropDown        matlab.ui.control.DropDown
        AcqusitionTypeLabel            matlab.ui.control.Label
        AcqusitionTypeDropDown_2       matlab.ui.control.DropDown
        LinearPolarizersPanel          matlab.ui.container.Panel
        Label_3                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        CurrentposLabel                matlab.ui.control.Label
        Rotate_Detection               matlab.ui.control.Button
        Label_2                        matlab.ui.control.Label
        Rotate_illumination            matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        DetectionEditFieldLabel        matlab.ui.control.Label
        DetectionEditField             matlab.ui.control.NumericEditField
        IlluminationEditFieldLabel     matlab.ui.control.Label
        IlluminationEditField          matlab.ui.control.NumericEditField
        SaveDirectoryLabel             matlab.ui.control.Label
        SaveDirectory                  matlab.ui.control.EditField
        BrowseButton_2                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        UL_x; %
        UL_y; % 
        det_degree_settings; %
        ill_degree_settings; % 
        det_RCA_LCA_settings; %
        ill_RCA_LCA_settings; %
        motor_x; %
        motor_y; %
        motor_ill; %
        motor_det; %
        save_folder; %
        z_step = 1; % 1 micron
        saved_coordinates = cell(1,1); %
        saved_coordinates_string = cell(1,1); %
        saved_z_coordinates = zeros(1,3); %
        saved_z_coordinates_string = cell(1,1); %
        
        zstack_display = zeros(1200,1600,41); %
        zstack_fm = zeros(41,1); %
        i_zstack = 0; %
        
        % FOV in microns calculated from calibration slide for each magnification
        mag_list = {'4x','10x','16x','20x','32x','40x','64x','60x','96x'};
        mags = [4 10 16 20 32 40 64 60 96]';
        pixels_per_100_microns = [54 136 210 270 420 542 840 815 1310]';
        FOV_pixels = [1200; 1600]; % [y, x]
        
        % 
        mag_index = 8;
        obj_string = '';
        
        overlap_for_stitching = 0.25; % percent
        
        stitching_perimeter = zeros(1,2); %
        stitching_coordinates = 0; %
        rectangular_stitching = 0; %
        perimeter_stitching = 0; %
        rectangular_stitch_folder = ''; %
        perimeter_stitch_folder = ''; %
        ULx = 0; %
        ULy = 0; %
        LRx = 0; %
        LRy = 0; %
        grid_size_x = 2; %
        grid_size_y = 2; %
        x_grid_mesh; %
        y_grid_mesh; %
        x_img_mesh; %
        y_img_mesh; %
        empty_tile = false; %
        z_img_mesh = 0; %
        FOV_microns_y; %
        FOV_microns_x; %
        FOV_microns_stitching_y; %
        FOV_microns_stitching_x; %
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%% add ons for new GUI
        %%%%%%%%%%%%%%%%%%%%%%%%%% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        path_location = '\\ad\eng\users\a\l\algray\My Documents\MATLAB\qBRM\'; % main folder location
        Experimental_file_location = ''; % create experiment folder
        imaging_setup = {'Cross Circular Polarizer LCP-RCA', 'Cross Circular Polarizer RCP-LCA', 'Brightfield RCP-RCA', 'Brightfield LCP-LCA','Rotating Linear Polarizer'};
        
    end
    
%     methods (Access = private)
%         function [x_coord, y_coord] = read_motor_position(app)
%                 % read position of the motor
%                 x_coord = System.Decimal.ToDouble(app.motor_x.Position);
%                 y_coord = System.Decimal.ToDouble(app.motor_y.Position);      
%         end
%             
%         function move_motor(app, x_coord, y_coord)
%                 % moves XY stage to preset position
%                 moveto(app, app.motor_x, x_coord);
%                 moveto(app, app.motor_y, y_coord);
%         end
%             
%         function moveto(app, motor, position)
%             try
%                 workDone=motor.InitializeWaitHandler(); % Initialise Waithandler for timeout
%                 motor.MoveTo(position, workDone);       % Move devce to position via .NET interface
%                 motor.Wait(100000);              % Wait for move to finish
%             catch
%                 disp('Unable to Move Device. Device will be homed')
%                 motor.Home(100000);
%             end
%         end
%     end 
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create GUIDE-style callback args - Added by Migration Tool
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%% %%%%%%%%%%%%%%%%%%%%%%%%% %%%%%%%%%%%%%%%%%%%%%%%%% %%%%%%%%%%%%%%%%%%%%%%%%%
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
             %%%%%%%%%%%%%%%%%%%%%%%%% %%%%%%%%%%%%%%%%%%%%%%%%% %%%%%%%%%%%%%%%%%%%%%%%%% %%%%%%%%%%%%%%%%%%%%%%%%%


            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to control_new_offline_copy (see VARARGIN)
            
            % Choose default command line output for control_new_offline_copy
%             current_folder = pwd;
%             addpath(current_folder);
%             [~, bigio_index_end] = regexp(current_folder,'_Bigio');
%             bigio_folder = current_folder(1:bigio_index_end);
%             
%             % Include path to MATLAB folder
%             matlab_folder = [bigio_folder,'\MATLAB'];
%             addpath(matlab_folder);
%             
%             % Include path to functions
%             addpath([matlab_folder,'\functions']);
%             % Load motorized rotational stages and settings
%             for nnn = 1:1
%                 calibration_path{1,1} = [matlab_folder,'\Calibration_files\crossed_polarizer_settings'];
%                 addpath(calibration_path{1,1});
%                 ill_degree_settings = load('ill_5_degree_settings.mat'); %illumination
%                 det_degree_settings = load('det_5_degree_settings.mat'); %detection
%                 calibration_path{2,1} = [matlab_folder,'\Calibration_files\circular_analyzer_settings'];
%                 addpath(calibration_path{2,1});
%                 ill_RCA_LCA_settings = load('ill_RCA_LCA_settings.mat');
%                 det_RCA_LCA_settings = load('det_RCA_LCA_settings.mat');
%                 addpath('C:\Program Files\Micro-Manager-2.0gamma'); % Add path to MMCoreJ_wrap.dll
%                 % Also must copy path to MMCoreJ.jar to classpath.txt located in %matlabroot\toolbox\local
%             
%                 field_names = fieldnames(det_degree_settings);
%                 app.det_degree_settings = det_degree_settings.(field_names{1,1});
%                 field_names = fieldnames(ill_degree_settings);
%                 app.ill_degree_settings = ill_degree_settings.(field_names{1,1});
%                 field_names = fieldnames(det_RCA_LCA_settings);
%                 app.det_RCA_LCA_settings = det_RCA_LCA_settings.(field_names{1,1});
%                 field_names = fieldnames(ill_RCA_LCA_settings);
%                 app.ill_RCA_LCA_settings = ill_RCA_LCA_settings.(field_names{1,1});
%                 clearvars field_names
%             
%                 SN_ill = '55001048';
%                 SN_det = '55001045';
%                 SN_xy_controller = '73109054';
%             
%                 %create motor objects and home devices
%                 [app.motor_x, app.motor_y] = motor_init(SN_xy_controller);
%                 app.motor_ill = rotat_stage_init(SN_ill);
%                 app.motor_det = rotat_stage_init(SN_det);
% 
%             end
%             
%             % Start in LCP-RCA configuration
%             app.motor_ill.MoveTo(app.ill_RCA_LCA_settings(1,1), 60000);
%             app.motor_det.MoveTo(app.det_RCA_LCA_settings(1,1), 60000);
% 
%             
%             app.ill_pol_display.Text = sprintf('%.4f degrees', app.ill_RCA_LCA_settings(1,1));
%             app.det_pol_display.Text = sprintf('%.4f degrees', app.det_RCA_LCA_settings(1,1)); 
%             
%             % Create time-stamped save folder each time an imaging session is started
%             t = clock;
%             
%             
%             % can replace with an experimental file
%             new_folder_name = sprintf('%d_%02d_%02d_imaging_%02d_%02d_%02d',...
%                 t(1),t(2),t(3),t(4),t(5),round(t(6))); % t = [year, month, day, hour, minute, second]
%             cd([bigio_folder,'\Images']);
%             mkdir(new_folder_name);
%             
%             
%             
%             
%             app.save_folder = [bigio_folder,'\Images\',new_folder_name];
%             app.save_dir_user_entry.Value = app.save_folder;
%             cd(app.save_folder);
%             
%             max_slider_val = 36;
%             min_slider_val = 1;
%             slider_steps1 = 1/(max_slider_val - min_slider_val);
%             slider_steps2 = 3/(max_slider_val - min_slider_val);
%             app.LP_angle_slider.Limits = [min_slider_val max_slider_val];
%             
%             app.LP_angle_slider.MajorTicks = min_slider_val:slider_steps1:max_slider_val;
% 
%             !C:\Program Files\Micro-Manager-2.0gamma\ImageJ.exe
%             %app.camera_status.Text = 'Micro-Manager';
%             
%             
%             app.FOV_microns_y = 100*app.FOV_pixels(1,1)./app.pixels_per_100_microns; % First column --> y
%             app.FOV_microns_x = 100*app.FOV_pixels(2,1)./app.pixels_per_100_microns; % Second column --> x
%             app.FOV_microns_stitching_y = app.FOV_microns_y.*(1 - app.overlap_for_stitching);
%             app.FOV_microns_stitching_x = app.FOV_microns_x.*(1 - app.overlap_for_stitching);
%             disp('Initialization finished');
            current_folder = pwd;
            current_folder = strcat(current_folder, "\");
            %addpath(current_folder);
            %[~, bigio_index_end] = regexp(current_folder,'_Bigio');
            %bigio_folder = current_folder(1:bigio_index_end);
            %cd([bigio_folder,'\Images']);
            
            t = clock;
            
            % can replace with an experimental file
            new_folder_name = sprintf('%d_%02d_%02d_imaging_%02d_%02d_%02d',t(1),t(2),t(3),t(4),t(5),round(t(6))); % t = [year, month, day, hour, minute, second]
            new_folder_name = strcat(current_folder, new_folder_name);
            app.SaveDirectory.Value = new_folder_name;
            
            app.FileName.Value = new_folder_name;
            disp(app.MagnificationDropDown.Value);
            
            %%%% set all default values
            app.MagnificationDropDown.Value = app.mag_list{1,app.mag_index};
            app.Microscopy_status.Text = app.MicroscopeSetupDropDown.Value;
            app.Magnification_status.Text = app.MagnificationDropDown.Value;
            grid_1 = strcat(string(app.grid_size_x),'x');
            grid_string = strcat(grid_1,string(app.grid_size_y));
            disp(grid_string)
            app.Grid_size_status.Text = grid_string;
        end

        % Size changed function: ImageStitchingParametersPanel
        function CameraButtonGroupSelectionChanged(app, event)
            selectedButton = app.CameraButtonGroup.SelectedObject;
           if(strcmp(selectedButton.Text,'MATLAB')) 
               disp('MATLAB')
           else
               disp('MicroManager')
           end
        end

        % Callback function
        function CreateExperimentButtonPushed(app, event)
                
                experiment_file = strcat(app.path_location,app.FileName.Value);
                mkdir(experiment_file);
                app.SaveDirectoryLamp.Color = 'Green';
                
                app.FileLocationLabel.Text = strcat('File Location: ', experiment_file); % update label for experiment location 
        end

        % Callback function
        function AcuisitionTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.AcuisitionTypeButtonGroup.SelectedObject;
            imaging_type = strcmp(selectedButton.Text, app.imaging_setup);
            disp(imaging_type)
            
            
        end

        % Callback function
        function BrowseButtonPushed(app, event)
             user_selection = uigetdir();
             
             disp(user_selection);
             
             app.FileLocationLabel.Text = strcat('File Location: ', user_selection); % update label for experiment location 
             
             
        end

        % Value changed function: MagnificationDropDown
        function MagnificationDropDownValueChanged(app, event)
            value = app.MagnificationDropDown.Value;
            disp(value)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AcquisitionTab = uitab(app.TabGroup);
            app.AcquisitionTab.Title = 'Acquisition';
            app.AcquisitionStatusPanel = uipanel(app.AcquisitionTab);
            app.AcquisitionStatusPanel.Title = 'Acquisition Status';
            app.SaveDirectoryLampLabel = uilabel(app.AcquisitionStatusPanel);
            app.SaveDirectoryLampLabel.Text = 'Save Directory';
            app.SaveDirectoryLamp = uilamp(app.AcquisitionStatusPanel);
            app.StitchingAcquisitionLampLabel = uilabel(app.AcquisitionStatusPanel);
            app.StitchingAcquisitionLampLabel.Text = 'Stitching Acquisition ';
            app.StitchingAcquisitionLamp = uilamp(app.AcquisitionStatusPanel);
            app.ZFocusLampLabel = uilabel(app.AcquisitionStatusPanel);
            app.ZFocusLampLabel.Text = 'Z Focus';
            app.ZFocusLamp = uilamp(app.AcquisitionStatusPanel);
            app.GridStatusLampLabel = uilabel(app.AcquisitionStatusPanel);
            app.GridStatusLampLabel.Text = 'Grid Status';
            app.GridStatusLamp = uilamp(app.AcquisitionStatusPanel);
            app.FileLocationLabel = uilabel(app.AcquisitionTab);
            app.FileLocationLabel.Text = 'File Location: ';
            app.FileNameEditFieldLabel = uilabel(app.AcquisitionTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileName = uieditfield(app.AcquisitionTab, 'text');
            app.AcquireImagesButton = uibutton(app.AcquisitionTab, 'push');
            app.AcquireImagesButton.Text = 'Acquire Image(s)';
            app.Defaulttodate_time_Mag_acqtype_Label = uilabel(app.AcquisitionTab);
            app.Defaulttodate_time_Mag_acqtype_Label.Text = '* Default to date_time_Mag_acq-type_';
            app.SystemStatusPanel = uipanel(app.AcquisitionTab);
            app.SystemStatusPanel.Title = 'System Status:';
            app.CameraStatusMicromanagerLabel = uilabel(app.SystemStatusPanel);
            app.CameraStatusMicromanagerLabel.Text = 'Camera Status: Micromanager';
            app.MagnificationLabel_2 = uilabel(app.SystemStatusPanel);
            app.MagnificationLabel_2.Text = 'Magnification:';
            app.MicroscopyMethodLabel = uilabel(app.SystemStatusPanel);
            app.MicroscopyMethodLabel.Text = 'Microscopy Method: ';
            app.GridSizeLabel_2 = uilabel(app.SystemStatusPanel);
            app.GridSizeLabel_2.Text = 'Grid Size: ';
            app.Magnification_status = uilabel(app.SystemStatusPanel);
            app.Microscopy_status = uilabel(app.SystemStatusPanel);
            app.Grid_size_status = uilabel(app.SystemStatusPanel);
            app.XYstageControlPanel = uipanel(app.AcquisitionTab);
            app.XYstageControlPanel.Title = 'XY-stage Control';
            app.x_coord_entry = uieditfield(app.XYstageControlPanel, 'text');
            app.x_coord_entry.Value = '55';
            app.y_coord_entry = uieditfield(app.XYstageControlPanel, 'text');
            app.y_coord_entry.Value = '37.5';
            app.text54 = uilabel(app.XYstageControlPanel);
            app.text54.Text = 'X-Pos';
            app.text54_2 = uilabel(app.XYstageControlPanel);
            app.text54_2.Text = 'Y-Pos';
            app.MoveXYmotorButton = uibutton(app.XYstageControlPanel, 'push');
            app.MoveXYmotorButton.Text = 'Move XY-motor';
            app.text54_3 = uilabel(app.XYstageControlPanel);
            app.text54_3.Text = 'Move to';
            app.text54_4 = uilabel(app.XYstageControlPanel);
            app.text54_4.Text = {'Current '; 'position'};
            app.x_coord_text = uilabel(app.XYstageControlPanel);
            app.x_coord_text.Text = 'NaN';
            app.y_coord_text = uilabel(app.XYstageControlPanel);
            app.y_coord_text.Text = 'NaN';
            app.SnapShotPreviewButton = uibutton(app.AcquisitionTab, 'push');
            app.SnapShotPreviewButton.Text = 'SnapShot Preview';
            app.ImagePreviewPanel = uipanel(app.AcquisitionTab);
            app.ImagePreviewPanel.Title = 'Image Preview';
            app.Image = uiimage(app.ImagePreviewPanel);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.ImageStitchingParametersPanel = uipanel(app.SettingsTab);
            app.ImageStitchingParametersPanel.Title = 'Image Stitching Parameters';
            app.ImageStitchingParametersPanel.SizeChangedFcn = createCallbackFcn(app, @CameraButtonGroupSelectionChanged, true);
            app.GridSizeLabel = uilabel(app.ImageStitchingParametersPanel);
            app.GridSizeLabel.Text = 'Grid Size: ';
            app.EditField = uieditfield(app.ImageStitchingParametersPanel, 'numeric');
            app.EditField.Value = 2;
            app.xEditFieldLabel = uilabel(app.ImageStitchingParametersPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.ImageStitchingParametersPanel, 'numeric');
            app.xEditField.Value = 2;
            app.StartPositionLabel = uilabel(app.ImageStitchingParametersPanel);
            app.StartPositionLabel.Text = 'Start Position:';
            app.XEditFieldLabel = uilabel(app.ImageStitchingParametersPanel);
            app.XEditFieldLabel.Text = 'X:';
            app.XEditField = uieditfield(app.ImageStitchingParametersPanel, 'numeric');
            app.XEditField.Value = 12.456;
            app.YEditField = uieditfield(app.ImageStitchingParametersPanel, 'numeric');
            app.YEditField.Value = 12.5;
            app.YEditFieldLabel = uilabel(app.ImageStitchingParametersPanel);
            app.YEditFieldLabel.Text = 'Y:';
            app.Switch = uiswitch(app.ImageStitchingParametersPanel, 'slider');
            app.Switch.Items = {'Upper Left', 'Lower Right'};
            app.Switch.Value = 'Upper Left';
            app.mmLabel = uilabel(app.ImageStitchingParametersPanel);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.ImageStitchingParametersPanel);
            app.mmLabel_2.Text = 'mm';
            app.AutoFocusCheckBox = uicheckbox(app.ImageStitchingParametersPanel);
            app.AutoFocusCheckBox.Text = 'Auto Focus';
            app.StitchingTypeDropDownLabel = uilabel(app.ImageStitchingParametersPanel);
            app.StitchingTypeDropDownLabel.Text = 'Stitching Type';
            app.StitchingTypeDropDown = uidropdown(app.ImageStitchingParametersPanel);
            app.StitchingTypeDropDown.Items = {'Single Plane', 'Multi Plane'};
            app.StitchingTypeDropDown.Value = 'Single Plane';
            app.ImageSettingsPanel = uipanel(app.SettingsTab);
            app.ImageSettingsPanel.Title = 'Image Settings';
            app.MagnificationLabel = uilabel(app.ImageSettingsPanel);
            app.MagnificationLabel.Text = 'Magnification:';
            app.MagnificationDropDown = uidropdown(app.ImageSettingsPanel);
            app.MagnificationDropDown.Items = {'4x', '10x', '16x', '20x', '32x', '40x', '60x', '64x', '96x'};
            app.MagnificationDropDown.ValueChangedFcn = createCallbackFcn(app, @MagnificationDropDownValueChanged, true);
            app.MagnificationDropDown.Value = '4x';
            app.ExposureTime1EditFieldLabel = uilabel(app.ImageSettingsPanel);
            app.ExposureTime1EditFieldLabel.Text = 'Exposure Time (1)';
            app.ExposureTime1EditField = uieditfield(app.ImageSettingsPanel, 'numeric');
            app.ExposureTime1EditField.Value = 50;
            app.ExposureTime2EditFieldLabel = uilabel(app.ImageSettingsPanel);
            app.ExposureTime2EditFieldLabel.Text = 'Exposure Time (2)';
            app.ExposureTime2EditField = uieditfield(app.ImageSettingsPanel, 'numeric');
            app.ExposureTime2EditField.Value = 100;
            app.msLabel_2 = uilabel(app.ImageSettingsPanel);
            app.msLabel_2.Text = 'ms';
            app.msLabel = uilabel(app.ImageSettingsPanel);
            app.msLabel.Text = 'ms';
            app.AcquisitionPanel = uipanel(app.SettingsTab);
            app.AcquisitionPanel.Title = 'Acquisition';
            app.MicroscopeSetupLabel = uilabel(app.AcquisitionPanel);
            app.MicroscopeSetupLabel.Text = 'Microscope Setup:';
            app.MicroscopeSetupDropDown = uidropdown(app.AcquisitionPanel);
            app.MicroscopeSetupDropDown.Items = {'Crossed Circular Polarizer', 'qBRM', 'Brightfield Microscopy', 'Rotating Linear Polarizer'};
            app.MicroscopeSetupDropDown.Value = 'Crossed Circular Polarizer';
            app.AcqusitionTypeLabel = uilabel(app.AcquisitionPanel);
            app.AcqusitionTypeLabel.Text = 'Acqusition Type:';
            app.AcqusitionTypeDropDown_2 = uidropdown(app.AcquisitionPanel);
            app.AcqusitionTypeDropDown_2.Items = {'Single Image', 'Stitching', 'Widefield (4x)'};
            app.AcqusitionTypeDropDown_2.Value = 'Single Image';
            app.LinearPolarizersPanel = uipanel(app.SettingsTab);
            app.LinearPolarizersPanel.Title = 'Linear Polarizers:';
            app.Label_3 = uilabel(app.LinearPolarizersPanel);
            app.Label_3.Text = '14.8 °';
            app.Label_4 = uilabel(app.LinearPolarizersPanel);
            app.Label_4.Text = '120.03 ° ';
            app.CurrentposLabel = uilabel(app.LinearPolarizersPanel);
            app.CurrentposLabel.Text = '(Current pos )';
            app.Rotate_Detection = uibutton(app.LinearPolarizersPanel, 'push');
            app.Rotate_Detection.Text = 'Rotate';
            app.Label_2 = uilabel(app.LinearPolarizersPanel);
            app.Label_2.Text = '°';
            app.Rotate_illumination = uibutton(app.LinearPolarizersPanel, 'push');
            app.Rotate_illumination.Text = 'Rotate';
            app.Label = uilabel(app.LinearPolarizersPanel);
            app.Label.Text = '°';
            app.DetectionEditFieldLabel = uilabel(app.LinearPolarizersPanel);
            app.DetectionEditFieldLabel.Text = 'Detection';
            app.DetectionEditField = uieditfield(app.LinearPolarizersPanel, 'numeric');
            app.DetectionEditField.Limits = [0 180];
            app.IlluminationEditFieldLabel = uilabel(app.LinearPolarizersPanel);
            app.IlluminationEditFieldLabel.Text = 'Illumination';
            app.IlluminationEditField = uieditfield(app.LinearPolarizersPanel, 'numeric');
            app.IlluminationEditField.Limits = [0 180];
            app.BrowseButton_2 = uibutton(app.SettingsTab, 'push');
            app.BrowseButton_2.Text = 'Browse...';
            app.SaveDirectoryLabel = uilabel(app.SettingsTab);
            app.SaveDirectoryLabel.Text = 'Save Directory:';
            app.SaveDirectory = uieditfield(app.SettingsTab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BRM_Microscope

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end